classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CarregarNeuralNetworkMenu     matlab.ui.container.Menu
        GravarNeuralNetworkMenu       matlab.ui.container.Menu
        DesenharMenu                  matlab.ui.container.Menu
        SimulaaocomCaraterunicoPanel  matlab.ui.container.Panel
        SimulaaocomDatasetPanel       matlab.ui.container.Panel
        SimularcomDatasetButton       matlab.ui.control.Button
        InputsPanel_2                 matlab.ui.container.Panel
        DropDown_4                    matlab.ui.control.DropDown
        EscolherButton_2              matlab.ui.control.Button
        PastaparaSimulaaoLabel        matlab.ui.control.Label
        InputsPanel_3                 matlab.ui.container.Panel
        EscolherButton_3              matlab.ui.control.Button
        EditField_8                   matlab.ui.control.EditField
        FicheiroparaSimulaaoLabel     matlab.ui.control.Label
        SimularcomFicheiroButton      matlab.ui.control.Button
        SimularcomDesenhoButton       matlab.ui.control.Button
        CriaaodeNeuralNetworkPanel    matlab.ui.container.Panel
        CriaretreinarRedeButton       matlab.ui.control.Button
        InputsPanel                   matlab.ui.container.Panel
        EditField_9                   matlab.ui.control.EditField
        DropDown_3                    matlab.ui.control.DropDown
        NomedaredeLabel               matlab.ui.control.Label
        EscolherButton                matlab.ui.control.Button
        PastaparaTreinoLabel          matlab.ui.control.Label
        ConfiguraaodaRedePanel        matlab.ui.container.Panel
        FunaodeAtivaao2DropDown       matlab.ui.control.DropDown
        FunaodeAtivaao2DropDownLabel  matlab.ui.control.Label
        FunaodeAtivaao1DropDown       matlab.ui.control.DropDown
        FunaodeAtivaao1DropDownLabel  matlab.ui.control.Label
        EditField_4                   matlab.ui.control.NumericEditField
        EditField_3                   matlab.ui.control.NumericEditField
        EditField_2                   matlab.ui.control.NumericEditField
        EditField                     matlab.ui.control.NumericEditField
        testLabel                     matlab.ui.control.Label
        valLabel                      matlab.ui.control.Label
        trainLabel                    matlab.ui.control.Label
        FunaodeDivisaoDropDown        matlab.ui.control.DropDown
        FunaodeDivisaoDropDownLabel   matlab.ui.control.Label
        TopologiaLabel                matlab.ui.control.Label
        FunaodeTreinoDropDown         matlab.ui.control.DropDown
        FunaodeTreinoDropDownLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CriaretreinarRedeButton
        function CriaretreinarRedeButtonPushed(app, event)
            fprintf("%s", app.FunaodeTreinoDropDown.Value);

            trainNetwork(app.FunaodeTreinoDropDown.Value,app.FunaodeAtivaao1DropDown.Value,"tansig",app.FunaodeDivisaoDropDown.Value,app.EditField.Value,app.EditField_2.Value,app.EditField_3.Value,app.DropDown_3.Value,app.EditField_9.Value);
        end

        % Button pushed function: SimularcomDesenhoButton
        function SimularcomDesenhoButtonPushed(app, event)
            %             clc;  % Clear command window.
            % clear;  % Delete all variables.
            % close all;  % Close all figure windows except those created by imtool.
            imtool close all;  % Close all figure windows created by imtool.
            workspace;  % Make sure the workspace panel is showing.
            fontSize = 16;
            grayImage = ones(128,128); %imread(fullFileName);
            imshow(grayImage, []);
            axis on;
            title('Original Grayscale Image', 'FontSize', fontSize);
            set(gcf, 'Position', get(0,'Screensize')); % Maximize figure.
            message = sprintf('Draw your figure.\nLeft click and hold to begin drawing.\nSimply lift the mouse button to finish');
            uiwait(msgbox(message));
            % User draws here.
            hFH = drawfreehand();
            % Get the xy coordinates of where they drew.
            xy = hFH.Position;
            % get rid of imfreehand remnant.
            delete(hFH);
            % Overlay what they drew onto the image.
            hold on; % Keep image, and direction of y axis.
            disp(xy);
            xCoordinates = xy(:, 1);
            yCoordinates = xy(:, 2);
            plot(xCoordinates, yCoordinates, 'ro-', 'LineWidth', 2);
            caption = sprintf('Original Grayscale Image.\nPoints may not lie on adjacent pixels, depends on your speed of drawing!', 'FontSize', fontSize);
            title(caption, 'FontSize', fontSize);
            % Ask user if they want to burn the line into the image.
            promptMessage = sprintf('Do you want to burn the line into the image?');
            titleBarCaption = 'Continue?';
            button = questdlg(promptMessage, titleBarCaption, 'OK', 'Cancel', 'OK');
            if strcmpi(button, 'Cancel')
                return;
            end
            cla;
            hold off;
            for k = 1 : length(xCoordinates)
                row = int32(yCoordinates(k));
                column = int32(xCoordinates(k));
                fprintf("row: %d,  column: %d\n", row, column);
                grayImage(row, column) = 0;
            end
            imshow(grayImage);
            axis on;
            caption = sprintf('Grayscale Image with Burned In Curve.\nPoints may not lie on adjacent pixels, depends on your speed of drawing!', 'FontSize', fontSize);
            title(caption, 'FontSize', fontSize);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CarregarNeuralNetworkMenu = uimenu(app.UIFigure);
            app.CarregarNeuralNetworkMenu.Text = 'Carregar Neural Network';
            app.GravarNeuralNetworkMenu = uimenu(app.UIFigure);
            app.GravarNeuralNetworkMenu.Text = 'Gravar Neural Network';
            app.DesenharMenu = uimenu(app.UIFigure);
            app.DesenharMenu.Text = 'Desenhar';
            app.CriaaodeNeuralNetworkPanel = uipanel(app.UIFigure);
            app.CriaaodeNeuralNetworkPanel.Title = 'Criaçao de Neural Network';
            app.ConfiguraaodaRedePanel = uipanel(app.CriaaodeNeuralNetworkPanel);
            app.ConfiguraaodaRedePanel.Title = 'Configuraçao da Rede';
            app.FunaodeTreinoDropDownLabel = uilabel(app.ConfiguraaodaRedePanel);
            app.FunaodeTreinoDropDownLabel.Text = {'Funçao de Treino'; ''};
            app.FunaodeTreinoDropDown = uidropdown(app.ConfiguraaodaRedePanel);
            app.FunaodeTreinoDropDown.Items = {'trainlm', 'trainbfg', 'trainrp', 'trainscg', 'traincgb', 'traincgf', 'traincgp', 'trainoss', 'traingdx'};
            app.FunaodeTreinoDropDown.Value = 'trainlm';
            app.TopologiaLabel = uilabel(app.ConfiguraaodaRedePanel);
            app.TopologiaLabel.Text = 'Topologia';
            app.FunaodeDivisaoDropDownLabel = uilabel(app.ConfiguraaodaRedePanel);
            app.FunaodeDivisaoDropDownLabel.Text = 'Funçao de Divisao';
            app.FunaodeDivisaoDropDown = uidropdown(app.ConfiguraaodaRedePanel);
            app.FunaodeDivisaoDropDown.Items = {'dividerand', 'divideblock', 'divideint', 'divideind'};
            app.FunaodeDivisaoDropDown.Value = 'dividerand';
            app.trainLabel = uilabel(app.ConfiguraaodaRedePanel);
            app.trainLabel.Text = 'train';
            app.valLabel = uilabel(app.ConfiguraaodaRedePanel);
            app.valLabel.Text = 'val';
            app.testLabel = uilabel(app.ConfiguraaodaRedePanel);
            app.testLabel.Text = 'test';
            app.EditField = uieditfield(app.ConfiguraaodaRedePanel, 'numeric');
            app.EditField.Value = 0.7;
            app.EditField_2 = uieditfield(app.ConfiguraaodaRedePanel, 'numeric');
            app.EditField_2.Value = 0.15;
            app.EditField_3 = uieditfield(app.ConfiguraaodaRedePanel, 'numeric');
            app.EditField_3.Value = 0.15;
            app.EditField_4 = uieditfield(app.ConfiguraaodaRedePanel, 'numeric');
            app.FunaodeAtivaao1DropDownLabel = uilabel(app.ConfiguraaodaRedePanel);
            app.FunaodeAtivaao1DropDownLabel.Text = 'Funçao de Ativaçao 1';
            app.FunaodeAtivaao1DropDown = uidropdown(app.ConfiguraaodaRedePanel);
            app.FunaodeAtivaao1DropDown.Items = {'tansig', 'logsing', 'tansig', 'purelin'};
            app.FunaodeAtivaao1DropDown.Value = 'tansig';
            app.FunaodeAtivaao2DropDownLabel = uilabel(app.ConfiguraaodaRedePanel);
            app.FunaodeAtivaao2DropDownLabel.Text = 'Funçao de Ativaçao 2';
            app.FunaodeAtivaao2DropDown = uidropdown(app.ConfiguraaodaRedePanel);
            app.FunaodeAtivaao2DropDown.Items = {'tansig', 'logsing', 'tansig', 'purelin'};
            app.FunaodeAtivaao2DropDown.Value = 'tansig';
            app.InputsPanel = uipanel(app.CriaaodeNeuralNetworkPanel);
            app.InputsPanel.Title = 'Inputs';
            app.PastaparaTreinoLabel = uilabel(app.InputsPanel);
            app.PastaparaTreinoLabel.Text = 'Pasta para Treino';
            app.EscolherButton = uibutton(app.InputsPanel, 'push');
            app.EscolherButton.Text = 'Escolher';
            app.NomedaredeLabel = uilabel(app.InputsPanel);
            app.NomedaredeLabel.Text = 'Nome da rede';
            app.DropDown_3 = uidropdown(app.InputsPanel);
            app.DropDown_3.Items = {'start', 'test', 'train', ''};
            app.DropDown_3.Value = 'start';
            app.EditField_9 = uieditfield(app.InputsPanel, 'text');
            app.CriaretreinarRedeButton = uibutton(app.CriaaodeNeuralNetworkPanel, 'push');
            app.CriaretreinarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @CriaretreinarRedeButtonPushed, true);
            app.CriaretreinarRedeButton.Text = 'Criar e treinar Rede';
            app.SimulaaocomCaraterunicoPanel = uipanel(app.UIFigure);
            app.SimulaaocomCaraterunicoPanel.Title = 'Simulaçao com Carater unico';
            app.SimularcomDesenhoButton = uibutton(app.SimulaaocomCaraterunicoPanel, 'push');
            app.SimularcomDesenhoButton.ButtonPushedFcn = createCallbackFcn(app, @SimularcomDesenhoButtonPushed, true);
            app.SimularcomDesenhoButton.Text = 'Simular com Desenho';
            app.SimularcomFicheiroButton = uibutton(app.SimulaaocomCaraterunicoPanel, 'push');
            app.SimularcomFicheiroButton.Text = 'Simular com Ficheiro';
            app.InputsPanel_3 = uipanel(app.SimulaaocomCaraterunicoPanel);
            app.InputsPanel_3.Title = 'Inputs';
            app.FicheiroparaSimulaaoLabel = uilabel(app.InputsPanel_3);
            app.FicheiroparaSimulaaoLabel.Text = 'Ficheiro para Simulaçao';
            app.EditField_8 = uieditfield(app.InputsPanel_3, 'text');
            app.EscolherButton_3 = uibutton(app.InputsPanel_3, 'push');
            app.EscolherButton_3.Text = 'Escolher';
            app.SimulaaocomDatasetPanel = uipanel(app.SimulaaocomCaraterunicoPanel);
            app.SimulaaocomDatasetPanel.Title = 'Simulaçao com Dataset';
            app.InputsPanel_2 = uipanel(app.SimulaaocomDatasetPanel);
            app.InputsPanel_2.Title = 'Inputs';
            app.PastaparaSimulaaoLabel = uilabel(app.InputsPanel_2);
            app.PastaparaSimulaaoLabel.Text = 'Pasta para Simulaçao';
            app.EscolherButton_2 = uibutton(app.InputsPanel_2, 'push');
            app.EscolherButton_2.Text = 'Escolher';
            app.DropDown_4 = uidropdown(app.InputsPanel_2);
            app.DropDown_4.Items = {'start', 'test', 'train', ''};
            app.DropDown_4.Value = 'start';
            app.SimularcomDatasetButton = uibutton(app.SimulaaocomDatasetPanel, 'push');
            app.SimularcomDatasetButton.Text = 'Simular com Dataset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end